classdef timerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        goButton            matlab.ui.control.Button
        sampleSpinnerLabel  matlab.ui.control.Label
        sampleSpinner       matlab.ui.control.Spinner
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timerProp % Description
    end
    
        methods (Access = private)
    
            
        function timerFunc(app,~,~)
            global ii
            
            ii = ii+.1;
            app.sampleSpinner.Value = ii;   
            
            a = mod(ii, 2*pi)+pi;
            plot(app.UIAxes, cos(-a), sin(-a), "Marker",".","MarkerSize",30);
       end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global running ii
            running = -1;
            ii = 0;
            
                        % Create timer object
            app.timerProp = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', .5, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.timerFunc);      % Specify callback function
        end

        % Button pushed function: goButton
        function goButtonPushed(app, event)
            global running 
            running = running * -1;
            disp(running)

            
            if running > 0
                start(app.timerProp);
                app.goButton.BackgroundColor = [0.4667    0.6745    0.1882];
            else
                stop(app.timerProp);
                app.goButton.BackgroundColor = [0.8 0.8 0.8];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.goButton = uibutton(app.UIFigure, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Text = 'go';
            app.sampleSpinnerLabel = uilabel(app.UIFigure);
            app.sampleSpinnerLabel.Text = 'sample';
            app.sampleSpinner = uispinner(app.UIFigure);
            app.sampleSpinner.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1.1 1.1];
            app.UIAxes.YLim = [-1.1 1.1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end